classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectsourceDropDownLabel  matlab.ui.control.Label
        SelectsourceDropDown       matlab.ui.control.DropDown
        SwitchLabel                matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
                               
        function play(app,input)
            if strcmp(input,'Use webcam')
                vid=webcam('Logitech');
            else
                try
                    if strcmp(input,'MOT16')
                        vid=VideoReader('vid/MOT16-03-raw.webm');
                    elseif strcmp(input,'Recorded')
                        vid=VideoReader('vid/gioi_outdoor_night.mp4');
                    else
                        % Display uigetfile dialog
                        filterspec = {'*.webm;*.mp4;*.avi','All Video Files'};
                        [f, p] = uigetfile(filterspec);
                        
                        % Make sure user didn't cancel uigetfile dialog
                        if (ischar(p))
                            fname = [p f];
                            vid = VideoReader(fname);
                        end
                    end
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Video Error');
                    return;
                end
            end
            while hasFrame(vid)
                frame = readFrame(vid);
                imshow(frame, 'Parent', app.UIAxes);
                pause(2/vid.FrameRate);
            end
        end
   
        function detectt(app,input)
            if strcmp(input,'Use webcam')
                vid=webcam('Logitech');
            else
                try
                    if strcmp(input,'MOT16')
                        vid=VideoReader('vid/MOT16-03-raw.webm');
                    elseif strcmp(input,'Recorded')
                        vid=VideoReader('vid/gioi_outdoor_night.mp4');
                    else
                        % Display uigetfile dialog
                        filterspec = {'*.webm;*.mp4;*.avi','All Video Files'};
                        [f, p] = uigetfile(filterspec);
                        
                        % Make sure user didn't cancel uigetfile dialog
                        if (ischar(p))
                            fname = [p f];
                            vid = VideoReader(fname);
                        end
                    end
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Video Error');
                    return;
                end
            end
            
            addpath('CustomLayers/','utils/');
            
            cfg_file = 'cfg/yolov4-tiny.cfg'; %load pretrained weights
            weight_file = 'weights/yolov4-tiny.weights';
            throushold = 0.5; %set detection treshold
            NMS = 0.5; %set allowed crossing boundaries threshold
            
            fid = fopen('coco.names','r');
            names = textscan(fid, '%s', 'Delimiter',{'   '});
            fclose(fid);
            classesNames = categorical(names{1});
            classesNames = categorical(names{1});
            
            tracker = multiObjectTracker(...
                'FilterInitializationFcn', @initDemoFilter, ...
                'AssignmentThreshold', 30, ...
                'DeletionThreshold', 22, ...
                'ConfirmationThreshold', [6 10] ...
                );
            
            function filter = initDemoFilter(detection)
                % Initialize a Kalman filter for this example.
                
                % Define the initial state.
                state = [detection.Measurement(1); 0; detection.Measurement(2); 0];
                
                % Define the initial state covariance.
                stateCov = diag([50, 50, 50, 50]);
                
                % Create the tracking filter.
                filter = trackingKF('MotionModel', '2D Constant Velocity', ...
                    'State', state, ...
                    'StateCovariance', stateCov, ...
                    'MeasurementNoise', detection.MeasurementNoise(1:2,1:2) ...
                    );
            end
            
            
            frameCount = 0;
            % Detect moving objects, and track them across video frames.
            while hasFrame(vid)
                frameCount = frameCount + 1;
                frame = readFrame(vid);
                width = size(frame,2)/416;
                height = size(frame,1)/416;
                frame1 = imresize(im2single(frame),[416 416]);
                
                detections = detectObjects(frame1);
                
                % Run the tracker on the preprocessed detections.
                confirmedTracks = updateTracks(tracker, detections, frameCount);
                
                assignin('base','tracks1',confirmedTracks);
                
                displayTrackingResults(confirmedTracks, frame);
                
            end
            
            %% Create System Objects
            % Create System objects used for reading the video frames
            
            
            %% Detect Objects
            
            function detections = detectObjects(frame)
                
                outFeatures = yolov3v4Predict(cfg_file,weight_file,frame,width,height);
                
                scores = outFeatures(:,5);
                outFeatures = outFeatures(scores>throushold,:);
                
                allBBoxes = outFeatures(:,1:4);
                allScores = outFeatures(:,5);
                [maxScores,indxs] = max(outFeatures(:,6:end),[],2);
                allScores = allScores.*maxScores;
                allLabels = classesNames(indxs);
                
                [bboxes,labels] = selectStrongestBboxMulticlass2(allBBoxes,allScores,allLabels,...
                    'RatioType','Min','OverlapThreshold',NMS);
                
                for i = 1:length(labels)
                    if labels(i)~='person'
                        bboxes(i,:)=zeros(1,4);
                        labels(i)= '0';
                        i=i+1;
                    end
                end
                bboxes(all(bboxes==0,2),:)=[];
                labels = labels(labels~='0');
                centroids = getLoc(bboxes);
                
                measurementNoise = 100*eye(2);
                
                % Formulate the detections as a list of objectDetection objects.
                numDetections = size(centroids, 1);
                detections = cell(numDetections, 1);
                for i = 1:numDetections
                    detections{i} = objectDetection(frameCount, centroids(i,:), ...
                        'MeasurementNoise', measurementNoise, ...
                        'ObjectAttributes', {bboxes(i,:)});
                end
            end
            
            %% Display Tracking Results
            % The |displayTrackingResults| function draws a bounding box and label ID
            % for each track on the video frame and foreground mask. It then displays
            % the frame and the mask in their respective video players.
            
            function displayTrackingResults(confirmedTracks, frame)
                % Convert the frame and the mask to uint8 RGB.
                frame = im2uint8(frame);
                
                if ~isempty(confirmedTracks)
                    % Display the objects. If an object has not been detected
                    % in this frame, display its predicted bounding box.
                    numRelTr = numel(confirmedTracks);
                    boxes = zeros(numRelTr, 4);
                    ids = zeros(numRelTr, 1, 'int32');
                    predictedTrackInds = zeros(numRelTr, 1);
                    
                    for tr = 1:numRelTr
                        % Get bounding boxes.
                        boxes(tr, :) = confirmedTracks(tr).ObjectAttributes{1}{1};
                        
                        % Get IDs.
                        ids(tr) = confirmedTracks(tr).TrackID;
                        
                        if confirmedTracks(tr).IsCoasted
                            predictedTrackInds(tr) = tr;
                        end
                    end
                    
                    predictedTrackInds = predictedTrackInds(predictedTrackInds > 0);
                    
                    % Create labels for objects that display the predicted rather
                    % than the actual location.
                    labels = cellstr(int2str(ids));
                    
                    isPredicted = cell(size(labels));
                    isPredicted(predictedTrackInds) = {' predicted'};
                    labels = strcat(labels, isPredicted);
                    
                    % Draw the objects on the frame.
                    frame = insertObjectAnnotation(frame, 'rectangle', boxes, labels);
                    
                end

        % Display the mask and the frame.       
        imshow(frame, 'Parent', app.UIAxes);
        pause(2/vid.FrameRate);
        
    end
        
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            axis(app.UIAxes, 'image');
          
                  
            % Update the image and histograms
            play(app, 'MOT16');
        end

        % Value changed function: SelectsourceDropDown
        function SelectsourceDropDownValueChanged(app, event)
            value = app.SelectsourceDropDown.Value;
            if strcmp(app.Switch.Value,'Off')
                play(app,value);
            else
                detectt(app,value);
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Off')
                play(app,app.SelectsourceDropDown.Value);
            else
                detectt(app,app.SelectsourceDropDown.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectsourceDropDownLabel = uilabel(app.UIFigure);
            app.SelectsourceDropDownLabel.Text = 'Select source';
            app.SelectsourceDropDown = uidropdown(app.UIFigure);
            app.SelectsourceDropDown.Items = {'MOT16', 'Recorded', 'Use webcam', 'Upload file'};
            app.SelectsourceDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectsourceDropDownValueChanged, true);
            app.SelectsourceDropDown.Value = 'MOT16';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Object Detection and Tracking')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end